classdef obfuscateModelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ObfuscateModelUIFigure         matlab.ui.Figure
        ModeltobeobfuscatedLabel       matlab.ui.control.Label
        StateflowPanel                 matlab.ui.container.Panel
        LabelsCheckBox                 matlab.ui.control.CheckBox
        FunctionsCheckBox              matlab.ui.control.CheckBox
        StatesCheckBox                 matlab.ui.control.CheckBox
        BoxesCheckBox                  matlab.ui.control.CheckBox
        ChartsCheckBox                 matlab.ui.control.CheckBox
        StateflowSwitch                matlab.ui.control.Switch
        EventsCheckBox                 matlab.ui.control.CheckBox
        StateflowPortsCheckBox         matlab.ui.control.CheckBox
        ResetSimulinkFunctionArgumentsCheckBox  matlab.ui.control.CheckBox
        ResetBlockNamesCheckBox        matlab.ui.control.CheckBox
        BackupButton                   matlab.ui.control.Button
        RemoveModelReferencesCheckBox  matlab.ui.control.CheckBox
        RemoveLibraryLinksCheckBox     matlab.ui.control.CheckBox
        NonFunctionalPanel             matlab.ui.container.Panel
        PositioningPanel               matlab.ui.container.Panel
        AutoPositioningCheckBox        matlab.ui.control.CheckBox
        BlockSizesCheckBox             matlab.ui.control.CheckBox
        SubsystemContentPreviewCheckBox  matlab.ui.control.CheckBox
        SubsystemPortLabelsCheckBox    matlab.ui.control.CheckBox
        ModelInformationCheckBox       matlab.ui.control.CheckBox
        DescriptionsCheckBox           matlab.ui.control.CheckBox
        AnnotationsCheckBox            matlab.ui.control.CheckBox
        DocBlocksCheckBox              matlab.ui.control.CheckBox
        ColorsPanel                    matlab.ui.container.Panel
        ColorSwitch                    matlab.ui.control.Switch
        ColourAnnotationsCheckBox      matlab.ui.control.CheckBox
        ColourBlocksCheckBox           matlab.ui.control.CheckBox
        FunctionalPanel                matlab.ui.container.Panel
        RemoveMasksCheckBox            matlab.ui.control.CheckBox
        ResetSimulinkFunctionNamesCheckBox  matlab.ui.control.CheckBox
        ResetDataStoreNamesCheckBox    matlab.ui.control.CheckBox
        ResetGotoFromsTagsCheckBox     matlab.ui.control.CheckBox
        ResetSignalNamesCheckBox       matlab.ui.control.CheckBox
        ResetDialogParametersCheckBox  matlab.ui.control.CheckBox
        RemoveBlockCallbacksCheckBox   matlab.ui.control.CheckBox
        ResetConstantsCheckBox         matlab.ui.control.CheckBox
        RecurseIntoModelReferencesCheckBox  matlab.ui.control.CheckBox
        SelectAllButton                matlab.ui.control.Button
        DeselectAllButton              matlab.ui.control.Button
        ModelNameEditField             matlab.ui.control.EditField
        ObfuscateButton                matlab.ui.control.Button
    end

    
    methods (Access = private)
        function updateCurrentSystem(app)
            opensys = gcs;
            if ~strcmp(opensys, 'simulink') && ~isempty(opensys)
                app.ModelNameEditField.Value = opensys;
                drawnow;
            end               
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateCurrentSystem(app);
        end

        % Value changed function: ColorSwitch
        function ColorSwitchValueChanged(app, event)
            value = app.ColorSwitch.Value;
            if strcmpi(value, 'On')
                app.ColourBlocksCheckBox.Enable = 'On';
                app.ColourAnnotationsCheckBox.Enable = 'On';
            elseif strcmpi(value, 'Off')
                app.ColourBlocksCheckBox.Enable = 'Off';
                app.ColourAnnotationsCheckBox.Enable = 'Off';
            end
        end

        % Value changed function: StateflowSwitch
        function StateflowSwitchValueChanged(app, event)
            value = app.StateflowSwitch.Value;
            if strcmpi(value, 'On')
                app.ChartsCheckBox.Enable = 'On';
                app.StateflowPortsCheckBox.Enable = 'On';
                app.EventsCheckBox.Enable = 'On';
                app.StatesCheckBox.Enable = 'On';
                app.BoxesCheckBox.Enable = 'On';
            elseif strcmpi(value, 'Off')
                app.ChartsCheckBox.Enable = 'Off';
                app.StateflowPortsCheckBox.Enable = 'Off';
                app.EventsCheckBox.Enable = 'Off';
                app.StatesCheckBox.Enable = 'Off';
                app.BoxesCheckBox.Enable = 'Off';
            end
        end

        % Button pushed function: ObfuscateButton
        function ObfuscateButtonPushed(app, event)
            app.ObfuscateButton.Text = "obfuscating ...";
            updateCurrentSystem(app);
            
            % Model argument
            sys = gcs;
            if isempty(sys)
                uialert(app.ObfuscateModelUIFigure, 'There is no currently opened or loaded model.', 'Model not found')
                return;
            end
            sys = strtok(sys, '/');
            
            if strcmp(get_param(sys, 'Lock'), 'on')
                input = uiconfirm(app.ObfuscateModelUIFigure, 'Unlock model?', 'Confirm Unlock');
                if strcmp(input, 'OK')
                    set_param(sys, 'Lock', 'off');
                else
                    return
                end
            end

            %backup the current version of the model is possible now
            app.BackupButton.Enable = "on";

                
            % Build argument list
            if strcmpi(app.ColorSwitch.Value, 'On')
                removecolorblocks = app.ColourBlocksCheckBox.Value;
                removecolorannotations = app.ColourAnnotationsCheckBox.Value;
            else
                removecolorblocks = 0;
                removecolorannotations = 0;
            end
            
            if strcmpi(app.StateflowSwitch.Value, 'On')
                sfcharts = app.ChartsCheckBox.Value;
                sfports  = app.StateflowPortsCheckBox.Value;
                sfevents = app.EventsCheckBox.Value;
                sfstates = app.StatesCheckBox.Value;
                sfboxes  = app.BoxesCheckBox.Value;
                sffunctions = app.FunctionsCheckBox.Value;
                sflabels = app.LabelsCheckBox.Value;
            else
                sfcharts = 0;
                sfports  = 0;
                sfevents = 0;
                sfstates = 0;
                sfboxes  = 0;
                sffunctions = 0;
                sflabels = 0;
            end
            args = {...
                'removemasks',            app.RemoveMasksCheckBox.Value, ...
                'removelibrarylinks',     app.RemoveLibraryLinksCheckBox.Value, ...
                'removemodelreferences',  app.RemoveModelReferencesCheckBox.Value, ...
                'removesignalnames',      app.ResetSignalNamesCheckBox.Value, ...
                'removedocblocks',        app.DocBlocksCheckBox.Value, ...
                'removeannotations',      app.AnnotationsCheckBox.Value, ...
                'removedescriptions',     app.DescriptionsCheckBox.Value, ...
                'removeblockcallbacks',   app.RemoveBlockCallbacksCheckBox.Value, ...
                'removemodelinformation', app.ModelInformationCheckBox.Value, ...
                'removecolorblocks',      removecolorblocks, ...
                'removecolorannotations', removecolorannotations, ...
                'removedialogparameters', app.ResetDialogParametersCheckBox.Value, ...
                'removepositioning',      app.AutoPositioningCheckBox.Value, ...
                'removesizes',            app.BlockSizesCheckBox.Value, ...
                'renameblocks',           app.ResetBlockNamesCheckBox.Value, ...
                'renameconstants',        app.ResetConstantsCheckBox.Value, ...
                'renamegotofromtag',      app.ResetGotoFromsTagsCheckBox.Value, ...
                'renamedatastorename',    app.ResetDataStoreNamesCheckBox.Value, ...
                'renamearguments',        app.ResetSimulinkFunctionArgumentsCheckBox.Value, ...
                'renamefunctions',        app.ResetSimulinkFunctionNamesCheckBox.Value, ...
                'hidecontentpreview',     app.SubsystemContentPreviewCheckBox.Value, ...
                'hideportlabels',         app.SubsystemPortLabelsCheckBox.Value, ...
                'sfcharts',               sfcharts, ...
                'sfports',                sfports, ...
                'sfevents',               sfevents, ...
                'sfstates',               sfstates, ...
                'sfboxes',                sfboxes, ...
                'sffunctions',            sffunctions, ...
                'sflabels',               sflabels, ...
                'recursemodels',          app.RecurseIntoModelReferencesCheckBox.Value};
            obfuscateModel(sys, [], args{:});
            clear global fcnNum;
            app.ObfuscateButton.Text = "Obfuscate";
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)
            fieldNames = fieldnames(app);
            allCheckBoxNamesList = fieldNames(contains(fieldNames, 'CheckBox', ...
                'IgnoreCase', false));
            for i = 1:numel(allCheckBoxNamesList)
                if ~strcmp(allCheckBoxNamesList{i}, 'CurrentlySelectedSystemCheckBox') ...
                        && ~strcmp(allCheckBoxNamesList{i}, 'RecurseIntoModelReferencesCheckBox')
                    %if sliders are in 'Off' position, ignore deselection
                    if strcmp(app.ColorSwitch.Value, 'Off') && ismember(app.(allCheckBoxNamesList{i}).Text, ["Blocks" "Annotations "]) || strcmp(app.StateflowSwitch.Value, 'Off') && ismember(app.(allCheckBoxNamesList{i}).Text, ["Charts" "Ports" "Events" "States" "Boxes"])
                        %do nothing
                    else
                        app.(allCheckBoxNamesList{i}).Value = false;
                    end
                end
            end
            app.RecurseIntoModelReferencesCheckBox.Enable = 'on';
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            fieldNames = fieldnames(app);
            allCheckBoxNamesList = fieldNames(contains(fieldNames, 'CheckBox', ...
                'IgnoreCase', false));
            for i = 1:numel(allCheckBoxNamesList)
                if ~strcmp(allCheckBoxNamesList{i}, 'CurrentlySelectedSystemCheckBox') ...
                        && ~strcmp(allCheckBoxNamesList{i}, 'RecurseIntoModelReferencesCheckBox')
                    %if sliders are in 'Off' position, ignore selection
                    if strcmp(app.ColorSwitch.Value, 'Off') && ismember(app.(allCheckBoxNamesList{i}).Text, ["Blocks" "Annotations "]) || strcmp(app.StateflowSwitch.Value, 'Off') && ismember(app.(allCheckBoxNamesList{i}).Text, ["Charts" "Ports" "Events" "States" "Boxes"])
                        %do nothing
                    else
                        app.(allCheckBoxNamesList{i}).Value = true;
                    end
                end
            end
            app.RecurseIntoModelReferencesCheckBox.Value = false;
            app.RecurseIntoModelReferencesCheckBox.Enable = 'off';
            app.RemoveModelReferencesCheckBox.Enable = 'on';
        end

        % Window button motion function: ObfuscateModelUIFigure
        function ObfuscateModelUIFigureWindowButtonMotion(app, event)
            updateCurrentSystem(app);
        end

        % Value changed function: RemoveModelReferencesCheckBox
        function RemoveModelReferencesCheckBoxValueChanged(app, event)
            checked = app.RemoveModelReferencesCheckBox.Value;
            if checked
                app.RecurseIntoModelReferencesCheckBox.Value = false;
                app.RecurseIntoModelReferencesCheckBox.Enable = 'off';
            else
                app.RecurseIntoModelReferencesCheckBox.Enable = 'on';
            end
        end

        % Value changed function: RecurseIntoModelReferencesCheckBox
        function RecurseIntoModelReferencesCheckBoxValueChanged(app, event)
            checked = app.RecurseIntoModelReferencesCheckBox.Value;
            if checked
                app.RemoveModelReferencesCheckBox.Value = false;
                app.RemoveModelReferencesCheckBox.Enable = 'off';
            else
                app.RemoveModelReferencesCheckBox.Enable = 'on';
            end
        end

        % Button pushed function: BackupButton
        function BackupButtonPushed(app, event)
            model_path = which(gcs);
            close_system(gcs, 0)
            open_system(model_path)
            app.BackupButton.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ObfuscateModelUIFigure = uifigure('Visible', 'off');
            app.ObfuscateModelUIFigure.Name = 'Obfuscate Model';
            app.ObfuscateModelUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @ObfuscateModelUIFigureWindowButtonMotion, true);
            app.ObfuscateButton = uibutton(app.ObfuscateModelUIFigure, 'push');
            app.ObfuscateButton.ButtonPushedFcn = createCallbackFcn(app, @ObfuscateButtonPushed, true);
            app.ObfuscateButton.Tooltip = {'Start model obfuscation. WARNING: Saving the file is irreversible.'};
            app.ObfuscateButton.Text = 'Obfuscate';
            app.ModelNameEditField = uieditfield(app.ObfuscateModelUIFigure, 'text');
            app.ModelNameEditField.Editable = 'off';
            app.ModelNameEditField.Enable = 'off';
            app.ModelNameEditField.Tooltip = {'Open a Simulink model, which you want to obfuscate. The currently opened model will be altered.'};
            app.DeselectAllButton = uibutton(app.ObfuscateModelUIFigure, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Tooltip = {'Deselect all of the options.'};
            app.DeselectAllButton.Text = 'Deselect All';
            app.SelectAllButton = uibutton(app.ObfuscateModelUIFigure, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Tooltip = {'Select all of the options.'};
            app.SelectAllButton.Text = 'Select All';
            app.RecurseIntoModelReferencesCheckBox = uicheckbox(app.ObfuscateModelUIFigure);
            app.RecurseIntoModelReferencesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecurseIntoModelReferencesCheckBoxValueChanged, true);
            app.RecurseIntoModelReferencesCheckBox.Tooltip = {'Apply the obfuscation to all nested model references.'};
            app.RecurseIntoModelReferencesCheckBox.Text = 'Recurse into Model References';
            app.RecurseIntoModelReferencesCheckBox.Value = true;
            app.FunctionalPanel = uipanel(app.ObfuscateModelUIFigure);
            app.FunctionalPanel.Title = 'Functional';
            app.ResetConstantsCheckBox = uicheckbox(app.FunctionalPanel);
            app.ResetConstantsCheckBox.Tooltip = {'Rename the ''Value'' parameter of Constant blocks.'};
            app.ResetConstantsCheckBox.Text = 'Reset Constants';
            app.ResetConstantsCheckBox.Value = true;
            app.RemoveBlockCallbacksCheckBox = uicheckbox(app.FunctionalPanel);
            app.RemoveBlockCallbacksCheckBox.Tooltip = {'Clear block callback parameters'; ' such as ''LoadFcn'', ''InitFcn'','; ' etc.'};
            app.RemoveBlockCallbacksCheckBox.Text = 'Remove Block Callbacks';
            app.RemoveBlockCallbacksCheckBox.Value = true;
            app.ResetDialogParametersCheckBox = uicheckbox(app.FunctionalPanel);
            app.ResetDialogParametersCheckBox.Tooltip = {'Resets dialog parameters to their default value. Currently only for built-in blocks.'};
            app.ResetDialogParametersCheckBox.Text = 'Reset Dialog Parameters';
            app.ResetDialogParametersCheckBox.Value = true;
            app.ResetSignalNamesCheckBox = uicheckbox(app.FunctionalPanel);
            app.ResetSignalNamesCheckBox.Tooltip = {'Remove signal names.'};
            app.ResetSignalNamesCheckBox.Text = 'Reset Signal Names';
            app.ResetSignalNamesCheckBox.Value = true;
            app.ResetGotoFromsTagsCheckBox = uicheckbox(app.FunctionalPanel);
            app.ResetGotoFromsTagsCheckBox.Tooltip = {'Rename the ''GotoTag'' parameter of Goto and From blocks (the parameter shown inside the  block).'};
            app.ResetGotoFromsTagsCheckBox.Text = 'Reset Goto/Froms Tags';
            app.ResetGotoFromsTagsCheckBox.Value = true;
            app.ResetDataStoreNamesCheckBox = uicheckbox(app.FunctionalPanel);
            app.ResetDataStoreNamesCheckBox.Tooltip = {'Rename the ''DataStoreName'' parameter of Data Store Memory, Read, and Write blocks.'};
            app.ResetDataStoreNamesCheckBox.Text = 'Reset Data Store Names';
            app.ResetDataStoreNamesCheckBox.Value = true;
            app.ResetSimulinkFunctionNamesCheckBox = uicheckbox(app.FunctionalPanel);
            app.ResetSimulinkFunctionNamesCheckBox.Tooltip = {'Rename the ''Function Name'' parameter of all triggers. Update Simulink Function callers also.'};
            app.ResetSimulinkFunctionNamesCheckBox.Text = 'Reset Simulink Function Names';
            app.ResetSimulinkFunctionNamesCheckBox.Value = true;
            app.RemoveMasksCheckBox = uicheckbox(app.FunctionalPanel);
            app.RemoveMasksCheckBox.Tooltip = {'Remove block masks.'};
            app.RemoveMasksCheckBox.Text = 'Remove Masks';
            app.RemoveMasksCheckBox.Value = true;
            app.NonFunctionalPanel = uipanel(app.ObfuscateModelUIFigure);
            app.NonFunctionalPanel.Title = 'Non-Functional';
            app.ColorsPanel = uipanel(app.NonFunctionalPanel);
            app.ColorsPanel.Tooltip = {'Remove colours.'};
            app.ColorsPanel.Title = 'Colors';
            app.ColourBlocksCheckBox = uicheckbox(app.ColorsPanel);
            app.ColourBlocksCheckBox.Tooltip = {'Restore default colors for all blocks.'};
            app.ColourBlocksCheckBox.Text = 'Blocks';
            app.ColourBlocksCheckBox.Value = true;
            app.ColourAnnotationsCheckBox = uicheckbox(app.ColorsPanel);
            app.ColourAnnotationsCheckBox.Tooltip = {'Restore default colors for all annotations.'};
            app.ColourAnnotationsCheckBox.Text = 'Annotations ';
            app.ColourAnnotationsCheckBox.Value = true;
            app.ColorSwitch = uiswitch(app.ColorsPanel, 'slider');
            app.ColorSwitch.ValueChangedFcn = createCallbackFcn(app, @ColorSwitchValueChanged, true);
            app.ColorSwitch.Tooltip = {'Enable or disable the removal of colors.'};
            app.ColorSwitch.Value = 'On';
            app.DocBlocksCheckBox = uicheckbox(app.NonFunctionalPanel);
            app.DocBlocksCheckBox.Tooltip = {'Remove all DocBlocks.'};
            app.DocBlocksCheckBox.Text = 'DocBlocks';
            app.DocBlocksCheckBox.Value = true;
            app.AnnotationsCheckBox = uicheckbox(app.NonFunctionalPanel);
            app.AnnotationsCheckBox.Tooltip = {'Remove all annotations.'};
            app.AnnotationsCheckBox.Text = 'Annotations';
            app.AnnotationsCheckBox.Value = true;
            app.DescriptionsCheckBox = uicheckbox(app.NonFunctionalPanel);
            app.DescriptionsCheckBox.Tooltip = {'Remove descriptive block parameters.'};
            app.DescriptionsCheckBox.Text = 'Descriptions';
            app.DescriptionsCheckBox.Value = true;
            app.ModelInformationCheckBox = uicheckbox(app.NonFunctionalPanel);
            app.ModelInformationCheckBox.Tooltip = {'Clear the information stored in the ''Model Properties''.'};
            app.ModelInformationCheckBox.Text = 'Model Information';
            app.ModelInformationCheckBox.Value = true;
            app.SubsystemPortLabelsCheckBox = uicheckbox(app.NonFunctionalPanel);
            app.SubsystemPortLabelsCheckBox.Tooltip = {'Hide the port labels shown in subsystems.'};
            app.SubsystemPortLabelsCheckBox.Text = 'Subsystem Port Labels';
            app.SubsystemPortLabelsCheckBox.Value = true;
            app.SubsystemContentPreviewCheckBox = uicheckbox(app.NonFunctionalPanel);
            app.SubsystemContentPreviewCheckBox.Tooltip = {'Hide the Content Preview shown in subsystems.'};
            app.SubsystemContentPreviewCheckBox.Text = 'Subsystem Content Preview';
            app.SubsystemContentPreviewCheckBox.Value = true;
            app.PositioningPanel = uipanel(app.NonFunctionalPanel);
            app.PositioningPanel.Title = 'Positioning';
            app.BlockSizesCheckBox = uicheckbox(app.PositioningPanel);
            app.BlockSizesCheckBox.Tooltip = {'Resets all shapes and sizes of blocks to their original.'};
            app.BlockSizesCheckBox.Text = 'Block Sizes';
            app.BlockSizesCheckBox.Value = true;
            app.AutoPositioningCheckBox = uicheckbox(app.PositioningPanel);
            app.AutoPositioningCheckBox.Tooltip = {'Resets every block to an auto-formatted position.'};
            app.AutoPositioningCheckBox.Text = 'Auto Positioning';
            app.AutoPositioningCheckBox.Value = true;
            app.RemoveLibraryLinksCheckBox = uicheckbox(app.ObfuscateModelUIFigure);
            app.RemoveLibraryLinksCheckBox.Tooltip = {'Remove links to libraries. This includes them in the model directly.'};
            app.RemoveLibraryLinksCheckBox.Text = 'Remove Library Links';
            app.RemoveLibraryLinksCheckBox.Value = true;
            app.RemoveModelReferencesCheckBox = uicheckbox(app.ObfuscateModelUIFigure);
            app.RemoveModelReferencesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveModelReferencesCheckBoxValueChanged, true);
            app.RemoveModelReferencesCheckBox.Tooltip = {'Remove references to other models.'};
            app.RemoveModelReferencesCheckBox.Text = 'Remove Model References';
            app.BackupButton = uibutton(app.ObfuscateModelUIFigure, 'push');
            app.BackupButton.ButtonPushedFcn = createCallbackFcn(app, @BackupButtonPushed, true);
            app.BackupButton.Enable = 'off';
            app.BackupButton.Tooltip = {'Reverse the last changes. Only possible until the model is saved.'};
            app.BackupButton.Text = 'Back up';
            app.ResetBlockNamesCheckBox = uicheckbox(app.ObfuscateModelUIFigure);
            app.ResetBlockNamesCheckBox.Tooltip = {'Change the ''Name'' parameters of all blocks to be generic.'};
            app.ResetBlockNamesCheckBox.Text = 'Reset Block Names';
            app.ResetBlockNamesCheckBox.Value = true;
            app.ResetSimulinkFunctionArgumentsCheckBox = uicheckbox(app.ObfuscateModelUIFigure);
            app.ResetSimulinkFunctionArgumentsCheckBox.Tooltip = {'Rename the ''ArgumentName'' parameter of Simulink Function argument in/out ports. Update Simulink Function callers also.'};
            app.ResetSimulinkFunctionArgumentsCheckBox.Text = 'Reset Simulink Function Arguments';
            app.ResetSimulinkFunctionArgumentsCheckBox.Value = true;
            app.StateflowPanel = uipanel(app.ObfuscateModelUIFigure);
            app.StateflowPanel.Tooltip = {'Rename Stateflow elements to be generic.'};
            app.StateflowPanel.Title = 'Stateflow';
            app.StateflowPortsCheckBox = uicheckbox(app.StateflowPanel);
            app.StateflowPortsCheckBox.Tooltip = {'Rename the ''Name'' parameter of Stateflow inports and outports.'};
            app.StateflowPortsCheckBox.Text = 'Ports';
            app.StateflowPortsCheckBox.Value = true;
            app.EventsCheckBox = uicheckbox(app.StateflowPanel);
            app.EventsCheckBox.Tooltip = {'Rename the ''Name'' parameter of Stateflow events.'};
            app.EventsCheckBox.Text = 'Events';
            app.EventsCheckBox.Value = true;
            app.StateflowSwitch = uiswitch(app.StateflowPanel, 'slider');
            app.StateflowSwitch.ValueChangedFcn = createCallbackFcn(app, @StateflowSwitchValueChanged, true);
            app.StateflowSwitch.Tooltip = {'Enable or disable the renaming of Stateflow elements.'};
            app.StateflowSwitch.Value = 'On';
            app.ChartsCheckBox = uicheckbox(app.StateflowPanel);
            app.ChartsCheckBox.Tooltip = {'Rename the Stateflow chart ''Name'' parameter.'};
            app.ChartsCheckBox.Text = 'Charts';
            app.ChartsCheckBox.Value = true;
            app.BoxesCheckBox = uicheckbox(app.StateflowPanel);
            app.BoxesCheckBox.Tooltip = {'Rename the ''Name'' parameter of Stateflow boxes.'};
            app.BoxesCheckBox.Text = 'Boxes';
            app.BoxesCheckBox.Value = true;
            app.StatesCheckBox = uicheckbox(app.StateflowPanel);
            app.StatesCheckBox.Tooltip = {'Rename the ''Name'' parameter of Stateflow states.'};
            app.StatesCheckBox.Text = 'States';
            app.StatesCheckBox.Value = true;
            app.FunctionsCheckBox = uicheckbox(app.StateflowPanel);
            app.FunctionsCheckBox.Tooltip = {'Removes Function names.'};
            app.FunctionsCheckBox.Text = 'Functions';
            app.FunctionsCheckBox.Value = true;
            app.LabelsCheckBox = uicheckbox(app.StateflowPanel);
            app.LabelsCheckBox.Tooltip = {'Shortens Names and resets numbers in Transition Labels.'};
            app.LabelsCheckBox.Text = 'Labels';
            app.LabelsCheckBox.Value = true;
            app.ModeltobeobfuscatedLabel = uilabel(app.ObfuscateModelUIFigure);
            app.ModeltobeobfuscatedLabel.Tooltip = {'Open a Simulink model, which you want to obfuscate. The currently opened model will be altered.'};
            app.ModeltobeobfuscatedLabel.Text = 'Model to be obfuscated:';
            % Show the figure after all components are created
            app.ObfuscateModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = obfuscateModelGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ObfuscateModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ObfuscateModelUIFigure)
        end
    end
end